classdef bandsintensitiesCSLsites < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AddROIButton_2              matlab.ui.control.Button
        NoBandButton_2              matlab.ui.control.Button
        BandextraLabel              matlab.ui.control.Label
        BGinstensityEditField       matlab.ui.control.EditField
        BGinstensityEditFieldLabel  matlab.ui.control.Label
        BackgroundROILabel          matlab.ui.control.Label
        BandSuHROILabel             matlab.ui.control.Label
        DefineangleButton           matlab.ui.control.Button
        ChannelButtonGroup          matlab.ui.container.ButtonGroup
        Button_4                    matlab.ui.control.RadioButton
        Button_3                    matlab.ui.control.RadioButton
        Button_2                    matlab.ui.control.RadioButton
        Button                      matlab.ui.control.RadioButton
        rotationSliderLabel         matlab.ui.control.Label
        rotationSlider              matlab.ui.control.Slider
        StackSliderLabel            matlab.ui.control.Label
        StackSlider                 matlab.ui.control.Slider
        RestoreimageButton          matlab.ui.control.Button
        AddROIButton                matlab.ui.control.Button
        NoBandButton                matlab.ui.control.Button
        MeasureButton               matlab.ui.control.Button
        ClosewosavingButton         matlab.ui.control.Button
        Startdrawing3ROIsButton     matlab.ui.control.Button
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageMatrix % loaded image from input
        currentImageMatrix %this one will select the channel and slice, to apply a mask
        ImageMatrixbackup
        Slices
        Channels
        ROI
        ROIdim
        SelectedChannel
        SelectedZ
        SelectedAngle
        StartROI
        SizeROI
        IntensityROI
        IntensityROI2
        backgroundROI1
        maskbg1
        maskbg2
        maskbg3
        mergemaskbg
        xgrid
        ygrid
        backgroundROI2
        backgroundROI3
        backgroundlevelsExport
    end
    
    methods (Access = private)
        
        function results = func(app)
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ImageMatrix, ROIdim)
            app.ImageMatrix = ImageMatrix;
            app.ImageMatrixbackup = ImageMatrix;
            app.Slices = size(ImageMatrix,3);
            app.Channels = size(ImageMatrix,4);
            app.ROIdim = ROIdim;
            % define Slider limits
            app.StackSlider.Limits = [1,app.Slices];
            app.StackSlider.MajorTicks = [1 : app.Slices]
            
            %define Rotation Limits
            app.rotationSlider.Limits = [-180, 180];
            
            % plot first slice
            app.SelectedChannel = 2;
            if size(ImageMatrix, 4) < 2
                app.SelectedChannel = 1;
            end
            app.SelectedZ = 5;
            imagesc(ImageMatrix(:,:,round(app.SelectedZ, 0), app.SelectedChannel), 'Parent', app.UIAxes);
            
        end

        % Callback function: StackSlider, StackSlider
        function StackChanged(app, event)
           
            app.SelectedZ  = round(app.StackSlider.Value, 0);
            imagesc(app.ImageMatrix(:, :, app.SelectedZ, app.SelectedChannel),'Parent', app.UIAxes);

        end

        % Selection changed function: ChannelButtonGroup
        function ChannelChange(app, event)
            
             if app.Button.Value == 1
                app.SelectedChannel = 1;
                 
             elseif app.Button_2.Value == 1
                    app.SelectedChannel = 2;
                 
             elseif app.Button_3.Value == 1
                    app.SelectedChannel = 3;
             
             elseif app.Button_4.Value == 1
                    app.SelectedChannel = 4;
             end
             
             
             imagesc(app.ImageMatrix(:, :, app.SelectedZ, app.SelectedChannel), 'Parent',app.UIAxes);


        end

        % Value changed function: rotationSlider
        function RotationChange(app, event)
            app.SelectedAngle = round(app.rotationSlider.Value, 0);
           % app.rotationSlider.ValueChangingFcn = imagesc(imrotate(app.ImageMatrix(:, :, app.SelectedZ, app.SelectedChannel), app.SelectedAngle, 'nearest', "crop"), 'Parent', app.UIAxes);
             
            imagesc(imrotate(app.ImageMatrix(:, :, app.SelectedZ, app.SelectedChannel), app.SelectedAngle, 'nearest', "crop"), 'Parent', app.UIAxes);
             
             
        end

        % Button pushed function: DefineangleButton
        function DefineangleButtonPushed(app, event)
            
            app.SelectedAngle = round(app.rotationSlider.Value, 0);
            
            app.ImageMatrix = imrotate(app.ImageMatrix, app.SelectedAngle, 'nearest', "crop");
            
        end

        % Button pushed function: RestoreimageButton
        function RestoreimageButtonPushed(app, event)
            app.ImageMatrix = app.ImageMatrixbackup;
            imagesc(app.ImageMatrix(:,:,app.SelectedZ, app.SelectedChannel), 'Parent', app.UIAxes);
            
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
         
         app.ROI = drawrectangle('Position',app.ROIdim, 'LineWidth', 1, "Parent", app.UIAxes);
         wait(app.ROI); 
         
         app.StartROI = round(app.ROI.Position(1,1:2), 0); 
         app.SizeROI = round(app.ROI.Position(1,3:4), 0); 

         
         app.IntensityROI = app.ImageMatrix(app.StartROI(2):(app.StartROI(2)+ app.SizeROI(2)), app.StartROI(1):(app.StartROI(1)+ app.SizeROI(1)), :, :);
         imagesc(app.IntensityROI(:, :, app.SelectedZ, app.SelectedChannel), "Parent", app.UIAxes2);
         app.IntensityROI = app.IntensityROI(:, :, app.SelectedZ, :);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            assignin("base", "BackgroundIntensityExport", app.backgroundlevelsExport)
            assignin("base", "ExportIntensityROI", app.IntensityROI);
            assignin("base", "ExportIntensityROI2", app.IntensityROI2);
            delete(app);
            
        end

        % Button pushed function: NoBandButton
        function NoBandButtonPushed(app, event)
            app.IntensityROI = NaN(app.ROIdim(4) + 1, app.ROIdim(3) + 1, app.Slices, app.Channels);
            
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
           
           app.mergemaskbg = mergemasks(app.maskbg1, app.maskbg2, app.maskbg3);
           
           app.mergemaskbg = repmat(app.mergemaskbg,[1 1 1 size(app.ImageMatrix, 4)]);
         
           app.currentImageMatrix = app.ImageMatrix(:,:, app.SelectedZ, :);
   
           app.backgroundlevelsExport = double(bsxfun(@times, double(app.currentImageMatrix),  double(app.mergemaskbg)));
           
           app.backgroundlevelsExport(app.backgroundlevelsExport == 0) = NaN;

           app.BGinstensityEditField.Value =  string( mean(app.backgroundlevelsExport, 'all', 'omitnan'));


        end

        % Button pushed function: ClosewosavingButton
        function ClosewosavingButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: Startdrawing3ROIsButton
        function Startdrawing3ROIsButtonPushed(app, event)
           delete(app.ROI);
           
           app.backgroundROI1 = drawcircle('LineWidth', 1, "Parent", app.UIAxes);
           wait(app.backgroundROI1);
           app.maskbg1 = createMask(app.backgroundROI1);
           
           app.backgroundROI2 = drawcircle('LineWidth', 1, "Parent", app.UIAxes);
           wait(app.backgroundROI2);
           app.maskbg2 = createMask(app.backgroundROI2);
           
           app.backgroundROI3 = drawcircle('LineWidth', 1, "Parent", app.UIAxes);
           wait(app.backgroundROI3);
           
           app.maskbg3 = createMask(app.backgroundROI3);


        end

        % Button pushed function: AddROIButton_2
        function AddROIButton_2Pushed(app, event)
            
         app.ROI = drawrectangle('Position',app.ROIdim, 'LineWidth', 1, "Parent", app.UIAxes);
         wait(app.ROI); 
         
         app.StartROI = round(app.ROI.Position(1,1:2), 0); 
         app.SizeROI = round(app.ROI.Position(1,3:4), 0); 

         
         app.IntensityROI2 = app.ImageMatrix(app.StartROI(2):(app.StartROI(2)+ app.SizeROI(2)), app.StartROI(1):(app.StartROI(1)+ app.SizeROI(1)), :, :);
         imagesc(app.IntensityROI2(:, :, app.SelectedZ, app.SelectedChannel), "Parent", app.UIAxes2_2);
         app.IntensityROI2 = app.IntensityROI2(:, :, app.SelectedZ, :);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 500];
            app.UIAxes.YLim = [0 500];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.Startdrawing3ROIsButton = uibutton(app.UIFigure, 'push');
            app.Startdrawing3ROIsButton.ButtonPushedFcn = createCallbackFcn(app, @Startdrawing3ROIsButtonPushed, true);
            app.Startdrawing3ROIsButton.Text = 'Start drawing 3 ROIs';
            app.ClosewosavingButton = uibutton(app.UIFigure, 'push');
            app.ClosewosavingButton.ButtonPushedFcn = createCallbackFcn(app, @ClosewosavingButtonPushed, true);
            app.ClosewosavingButton.Text = 'Close w/osaving';
            app.MeasureButton = uibutton(app.UIFigure, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.NoBandButton = uibutton(app.UIFigure, 'push');
            app.NoBandButton.ButtonPushedFcn = createCallbackFcn(app, @NoBandButtonPushed, true);
            app.NoBandButton.Text = 'No Band ';
            app.AddROIButton = uibutton(app.UIFigure, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.RestoreimageButton = uibutton(app.UIFigure, 'push');
            app.RestoreimageButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreimageButtonPushed, true);
            app.RestoreimageButton.Text = 'Restore image';
            app.StackSlider = uislider(app.UIFigure);
            app.StackSlider.MajorTicks = [];
            app.StackSlider.ValueChangedFcn = createCallbackFcn(app, @StackChanged, true);
            app.StackSlider.ValueChangingFcn = createCallbackFcn(app, @StackChanged, true);
            app.StackSlider.MinorTicks = [];
            app.StackSliderLabel = uilabel(app.UIFigure);
            app.StackSliderLabel.Text = 'Stack';
            app.rotationSlider = uislider(app.UIFigure);
            app.rotationSlider.Limits = [-180 180];
            app.rotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationChange, true);
            app.rotationSlider.MinorTicks = [];
            app.rotationSliderLabel = uilabel(app.UIFigure);
            app.rotationSliderLabel.Text = 'rotation';
            app.ChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelChange, true);
            app.ChannelButtonGroup.Title = 'Channel';
            app.Button = uiradiobutton(app.ChannelButtonGroup);
            app.Button.Text = '1';
            app.Button_2 = uiradiobutton(app.ChannelButtonGroup);
            app.Button_2.Text = '2';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ChannelButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.ChannelButtonGroup);
            app.Button_4.Text = '4';
            app.DefineangleButton = uibutton(app.UIFigure, 'push');
            app.DefineangleButton.ButtonPushedFcn = createCallbackFcn(app, @DefineangleButtonPushed, true);
            app.DefineangleButton.Text = 'Define angle';
            app.BandSuHROILabel = uilabel(app.UIFigure);
            app.BandSuHROILabel.Text = 'Band Su(H) ROI';
            app.BackgroundROILabel = uilabel(app.UIFigure);
            app.BackgroundROILabel.Text = 'Background ROI';
            app.BGinstensityEditFieldLabel = uilabel(app.UIFigure);
            app.BGinstensityEditFieldLabel.Text = 'BG instensity';
            app.BGinstensityEditField = uieditfield(app.UIFigure, 'text');
            app.BandextraLabel = uilabel(app.UIFigure);
            app.BandextraLabel.Text = 'Band extra';
            app.NoBandButton_2 = uibutton(app.UIFigure, 'push');
            app.NoBandButton_2.Text = 'No Band ';
            app.AddROIButton_2 = uibutton(app.UIFigure, 'push');
            app.AddROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddROIButton_2Pushed, true);
            app.AddROIButton_2.Text = 'Add ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bandsintensitiesCSLsites(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end